classdef PS_Quadcopter_Lab_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        UIAxes_motors                 matlab.ui.control.UIAxes
        UIAxes_yaw                    matlab.ui.control.UIAxes
        UIAxes_roll                   matlab.ui.control.UIAxes
        UIAxes_pitch                  matlab.ui.control.UIAxes
        UIAxes_zpos                   matlab.ui.control.UIAxes
        UIAxes_ypos                   matlab.ui.control.UIAxes
        UIAxes_xpos                   matlab.ui.control.UIAxes
        QuadRotorPSLabLabel           matlab.ui.control.Label
        GridLayout3                   matlab.ui.container.GridLayout
        Button                        matlab.ui.control.Button
        Slider                        matlab.ui.control.Slider
        Button_2                      matlab.ui.control.Button
        GridLayout2                   matlab.ui.container.GridLayout
        SaveRunButton                 matlab.ui.control.Button
        Exercise2                     matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        SensorsDropDown               matlab.ui.control.DropDown
        SensorsDropDownLabel          matlab.ui.control.Label
        ControllerRateDropDown        matlab.ui.control.DropDown
        ControllerRateDropDownLabel   matlab.ui.control.Label
        LayoutOffsetSliderLabel_2     matlab.ui.control.Label
        ControllerGainsDropDown       matlab.ui.control.DropDown
        ControllerGainsDropDownLabel  matlab.ui.control.Label
        LayoutOffsetinXSlider         matlab.ui.control.Slider
        LayoutOffsetLabel             matlab.ui.control.Label
        CtrlMassDeviationSlider       matlab.ui.control.Slider
        CtrlMassDeviationSliderLabel  matlab.ui.control.Label
        Exercise3                     matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        AltitudePIDGainsLabel         matlab.ui.control.Label
        YawPIDGainsLabel              matlab.ui.control.Label
        D_yawEditField                matlab.ui.control.NumericEditField
        D_yawEditFieldLabel           matlab.ui.control.Label
        I_yawEditField                matlab.ui.control.NumericEditField
        I_yawEditFieldLabel           matlab.ui.control.Label
        P_yawEditField                matlab.ui.control.NumericEditField
        P_yawEditFieldLabel           matlab.ui.control.Label
        D_zEditField                  matlab.ui.control.NumericEditField
        D_zEditFieldLabel             matlab.ui.control.Label
        I_zEditField                  matlab.ui.control.NumericEditField
        I_zEditFieldLabel             matlab.ui.control.Label
        P_zEditField                  matlab.ui.control.NumericEditField
        P_zEditFieldLabel             matlab.ui.control.Label
        YawstepdegEditField           matlab.ui.control.NumericEditField
        YawstepdegEditFieldLabel      matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        LoadModelButton               matlab.ui.control.Button
        SimulateButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        resplt %response plot
        sim_out
        name % modelname without extension
        modelname %slx filename
        modellocation
        nrotor_vehicle
        K_lqr
        setpoints
        extype % boolean indicating which exercise is loaded
        %Variables to store additional CAD geometries
        robot
        t
        SimStopTime
        paused
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        %TODO TEST AND DEBUG LATER MAYBE IMPLEMENT LATER
        
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            
            % ToDo: check that a model is loaded!


            % Check which model is loaded and load corresponding variables
            % (there might be a better way to do this for repeated runs...
            if app.extype %model is for exercise 2
                exercise02_simulation_parameters
                %app.nrotor_vehicle=nrotor_vehicle;
            else %model is for exercise 3
                exercise03_simulation_parameters
            end

            %Run the simulation in Simulink and get the results
            sim(app.name);
            app.sim_out = get_sim_data(logsout);
            app.t=app.sim_out.time;


            %% Initialize Plots for the results

            % 3D Plot
            % create a simple 3D representation of the drone
            app.robot= assembleRobot(app.UIAxes, app.sim_out);
            lmx_x = max(app.sim_out.position(:,1));
            lmn_x= min(app.sim_out.position(:,1));
            lmx_y = max(app.sim_out.position(:,2));
            lmn_y= min(app.sim_out.position(:,2));
            lmx_z = max(app.sim_out.position(:,3));
            lmn_z= min(app.sim_out.position(:,3));

            xlim(app.UIAxes,[lmn_x-0.05 lmx_x+0.05])
            ylim(app.UIAxes,[lmn_y-0.05 lmx_y+0.05])
            zlim(app.UIAxes,[lmn_z-0.05 lmx_z+0.05])

            hold(app.UIAxes,"on")
            app.setpoints=plot3(app.UIAxes, [0 0.5], [0,0.5] , [0,0.5],':or');
            hold(app.UIAxes,"off")

            app.UIAxes.XDir = 'reverse';
            app.UIAxes.YDir = 'reverse';

            % Initialize plot for state parameters
            % Add a reference signal and an empty trace for the results data...
            %xpos
            app.resplt.xpos=plot(app.UIAxes_xpos,0,0);
            hold(app.UIAxes_xpos,"on")
            app.resplt.xpos_ref=plot(app.UIAxes_xpos,app.sim_out.time,app.sim_out.ref(:,1));
            hold(app.UIAxes_xpos,"off")

            %ypos
            app.resplt.ypos=plot(app.UIAxes_ypos,0,0);
            hold(app.UIAxes_ypos,"on")
            app.resplt.ypos_ref=plot(app.UIAxes_ypos,app.sim_out.time,app.sim_out.ref(:,2));
            hold(app.UIAxes_ypos,"off")

            %zpos
            app.resplt.zpos=plot(app.UIAxes_zpos,0,0);
            hold(app.UIAxes_zpos,"on")
            app.resplt.zpos_ref=plot(app.UIAxes_zpos,app.sim_out.time,app.sim_out.ref(:,3));
            hold(app.UIAxes_zpos,"off")

            %roll
            app.resplt.roll=plot(app.UIAxes_roll,0,0);

            %pitch
            app.resplt.pitch=plot(app.UIAxes_pitch,0,0);

            %yaw
            app.resplt.yaw=plot(app.UIAxes_yaw,0,0);
            hold(app.UIAxes_yaw,"on")
            app.resplt.yaw_ref=plot(app.UIAxes_yaw,app.sim_out.time,app.sim_out.ref(:,4));
            hold(app.UIAxes_yaw,"off")

            %thrusts
            app.resplt.motors1=plot(app.UIAxes_motors,0,0);
            hold(app.UIAxes_motors,"on")
            app.resplt.motors2=plot(app.UIAxes_motors,0,0);
            app.resplt.motors3=plot(app.UIAxes_motors,0,0);
            app.resplt.motors4=plot(app.UIAxes_motors,0,0);
            hold(app.UIAxes_motors,"off")

            %% Animate the Results
            ind=1;
            app.paused=false;
            while (~app.paused & ind<=length(app.t))
                animateRobot(app,app.t(ind));
                ind=ind+1;
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            [~, ind] = min(abs(app.t - value));
            ts=app.t(ind);
            animateRobot(app,ts);
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.paused=false;
            value = app.Slider.Value;
            [~, ind] = min(abs(app.t - value));
            while (~app.paused & ind<=length(app.t))
                animateRobot(app,app.t(ind));
                ind=ind+1;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.paused=true;
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            try
                close_system(app.modelname,0)
            end
            [app.modelname, app.modellocation] = uigetfile;
            app.EditField.Value=app.modelname;
            [~, app.name, ~] = fileparts(app.modelname);
                     
            load_system(fullfile(app.modellocation, app.modelname))
            % check which model is loaded and enable corresponding panel

            app.extype=contains(app.modelname,'exercise02');
            if app.extype %model is for exercise 2
                app.Exercise2.Visible="on";
                app.Exercise2.Enable="on";
                app.Exercise3.Visible="off";
                app.Exercise3.Enable="off";

                %load some parameters already
                exercise02_get_vehicle_parameters
                exercise02_get_default_controller
                app.nrotor_vehicle=nrotor_vehicle;
                app.K_lqr=K_lqr;

            else %model is for exercise 3
                app.Exercise3.Visible="on";
                app.Exercise3.Enable="on";
                app.Exercise2.Visible="off";
                app.Exercise2.Enable="off";

                app.YawstepdegEditField.Value=10; %the same value as in the paramfile
                
                try %to load original parameters from model
                    app.P_zEditField.Value=str2double(get_param(app.name + "/Controller/outer control loop/P-gain Z", 'Gain'));
                    app.I_zEditField.Value=str2double(get_param(app.name + "/Controller/outer control loop/I-gain Z", 'Gain'));
                    app.D_zEditField.Value=str2double(get_param(app.name + "/Controller/outer control loop/D-gain Z", 'Gain'));
                    app.P_yawEditField.Value=str2double(get_param(app.name + "/Controller/outer control loop/P-gain Yaw", 'Gain'));
                    app.I_yawEditField.Value=str2double(get_param(app.name + "/Controller/outer control loop/I-gain Yaw", 'Gain'));
                    app.D_yawEditField.Value=str2double(get_param(app.name + "/Controller/outer control loop/D-gain Yaw", 'Gain'));
                catch
                    display("Failed to load PID values from Simulink model. Either this functionality is not implemented yet or the block names are incorrect.")
                    %there is nothing more to do here
                end

            end
            
         
        end

        % Value changed function: CtrlMassDeviationSlider
        function CtrlMassDeviationSliderValueChanged(app, event)
            mass_deviation = app.CtrlMassDeviationSlider.Value;
            app.nrotor_vehicle.mass_for_controller = app.nrotor_vehicle.mass_true*(1+mass_deviation*0.01);
            app.nrotor_vehicle.equilibrium_thrust = exercise02_compute_equilibrium_thrusts(app.nrotor_vehicle.layout_for_controller,app.nrotor_vehicle.mass_for_controller,9.81);
            set_param(app.name + "/Controller/equilibrium thrust (aka. feed-forward thrust)", 'Value', string(app.nrotor_vehicle.equilibrium_thrust));
        end

        % Value changed function: LayoutOffsetinXSlider
        function LayoutOffsetinXSliderValueChanged(app, event)
            layout_deviation = app.LayoutOffsetinXSlider.Value;
            x_baseline = 0.300;
            y_baseline = 0.300;
            x_offset = x_baseline * layout_deviation * 0.01;
            % y_offset = y_baseline * layout_deviation * 0.01;
            y_offset = y_baseline * 0.0 * 0.01;
            app.nrotor_vehicle.layout_for_controller = app.nrotor_vehicle.layout_true + repmat( [x_offset;y_offset;0.000] , 1 , size(app.nrotor_vehicle.layout_true,2) );
            app.nrotor_vehicle.equilibrium_thrust = exercise02_compute_equilibrium_thrusts(app.nrotor_vehicle.layout_for_controller,app.nrotor_vehicle.mass_for_controller,9.81);
            disp(app.nrotor_vehicle.layout_for_controller)
            disp(app.nrotor_vehicle.equilibrium_thrust)
            set_param(app.name + "/Controller/equilibrium thrust (aka. feed-forward thrust)", 'Value', string(app.nrotor_vehicle.equilibrium_thrust));
            set_param(app.name + "/Controller/layout of the vehicles propellers", 'Value', mat2str(app.nrotor_vehicle.layout_for_controller));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                close_system(app.modelname,0)
            end
            delete(app)
            
        end

        % Value changed function: YawstepdegEditField
        function YawstepdegEditFieldValueChanged(app, event)
            step_xyz_yaw = [0.5; 0.5; 0.5; app.YawstepdegEditField.Value*pi/180;];
            set_param(app.name + "/Reference generator/Step Change Reference Amplitude", 'Value', mat2str(step_xyz_yaw));
        end

        % Value changed function: P_zEditField
        function P_zEditFieldValueChanged(app, event)
            try
                set_param(app.name + "/Controller/outer control loop/P-gain Z", 'Gain', string(app.P_zEditField.Value));
            catch
                msgbox("Failed to set value in Simulink. Corresponding gain block is not implemented or is not named 'P-gain Z'.","Notification","warn")
            end
        end

        % Value changed function: I_zEditField
        function I_zEditFieldValueChanged(app, event)
            try
                set_param(app.name + "/Controller/outer control loop/I-gain Z", 'Gain', string(app.I_zEditField.Value));
            catch
                msgbox("Failed to set value in Simulink. Corresponding gain block is not implemented or is not named 'I-gain Z'.","Notification","warn")
            end
        end

        % Value changed function: D_zEditField
        function D_zEditFieldValueChanged(app, event)
            try
                set_param(app.name + "/Controller/outer control loop/D-gain Z", 'Gain', string(app.D_zEditField.Value));
            catch
                msgbox("Failed to set value in Simulink. Corresponding gain block is not implemented or is not named 'D-gain Z'.","Notification","warn")
            end
        end

        % Value changed function: P_yawEditField
        function P_yawEditFieldValueChanged(app, event)
            try
                set_param(app.name + "/Controller/outer control loop/P-gain Yaw", 'Gain', string(app.P_yawEditField.Value));
            catch
                msgbox("Failed to set value in Simulink. Corresponding gain block is not implemented or is not named 'P-gain Yaw'.","Notification","warn")
            end
        end

        % Value changed function: I_yawEditField
        function I_yawEditFieldValueChanged(app, event)
            try
                set_param(app.name + "/Controller/outer control loop/I-gain Yaw", 'Gain', string(app.I_yawEditField.Value));
            catch
                msgbox("Failed to set value in Simulink. Corresponding gain block is not implemented or is not named 'I-gain Yaw'.","Notification","warn")
            end
        end

        % Value changed function: D_yawEditField
        function D_yawEditFieldValueChanged(app, event)
            try
                set_param(app.name + "/Controller/outer control loop/D-gain Yaw", 'Gain', string(app.D_yawEditField.Value));
            catch
                msgbox("Failed to set value in Simulink. Corresponding gain block is not implemented or is not named 'D-gain Yaw'.","Notification","warn")
            end
        end

        % Value changed function: ControllerGainsDropDown
        function ControllerGainsDropDownValueChanged(app, event)
            try
                switch app.ControllerGainsDropDown.Value
                    case 'cont'
                        set_param(app.name + "/Controller/LQR gain matrix", 'Gain', mat2str(-app.K_lqr.continous_time));
                    case'20 Hz'
                        set_param(app.name + "/Controller/LQR gain matrix", 'Gain', mat2str(-app.K_lqr.discrete_time_20Hz));
                    case '50 Hz'
                        set_param(app.name + "/Controller/LQR gain matrix", 'Gain', mat2str(-app.K_lqr.discrete_time_50Hz));
                    case '100 Hz'
                        set_param(app.name + "/Controller/LQR gain matrix", 'Gain', mat2str(-app.K_lqr.discrete_time_100Hz));   
                    case '200 Hz'
                        set_param(app.name + "/Controller/LQR gain matrix", 'Gain', mat2str(-app.K_lqr.discrete_time_200Hz));
                end
            catch
                msgbox("The rate transition blocks have not been implemented correctly.","Warning","warn")
            end
        end

        % Value changed function: ControllerRateDropDown
        function ControllerRateDropDownValueChanged(app, event)
            try
                switch app.ControllerRateDropDown.Value
                    case 'cont'
                        %set_param(app.name + "/Controller/ControllerPace/",'Commented','through' %-> ctrl input would have same rate as sensor sample rate
                        set_param(app.name + "/Controller/ControllerPace",'Commented','through');
                    case '20 Hz'
                        set_param(app.name + "/Controller/ControllerPace/",'OutPortSampleTime','0.05','Commented','off');
                    case '50 Hz'
                        set_param(app.name + "/Controller/ControllerPace/",'OutPortSampleTime','0.02','Commented','off');
                    case '100 Hz'
                        set_param(app.name + "/Controller/ControllerPace/",'OutPortSampleTime','0.01','Commented','off');
                    case '200 Hz'
                        set_param(app.name + "/Controller/ControllerPace/",'OutPortSampleTime','0.005','Commented','off');
                end
            catch
                msgbox("The rate transition blocks have not been implemented correctly.","Warning","warn")
            end            
        end

        % Value changed function: SensorsDropDown
        function SensorsDropDownValueChanged(app, event)
            try
                switch app.SensorsDropDown.Value
                    case 'cont'
                        set_param(app.name + "/Controller/SensorSampleRate",'Commented','through');
                    case '20 Hz'
                        set_param(app.name + "/Controller/SensorSampleRate/", 'OutPortSampleTime','0.05','Commented','off')
                    case '50 Hz'
                        set_param(app.name + "/Controller/SensorSampleRate/", 'OutPortSampleTime','0.02','Commented','off')
                    case '100 Hz'
                        set_param(app.name + "/Controller/SensorSampleRate/", 'OutPortSampleTime','0.01','Commented','off');   
                    case '200 Hz'
                        set_param(app.name + "/Controller/SensorSampleRate/", 'OutPortSampleTime','0.005','Commented','off')
                end
            catch
                msgbox("The rate transition blocks have not been implemented correctly.","Warning","warn")
            end 
        end

        % Button pushed function: SaveRunButton
        function SaveRunButtonPushed(app, event)
            dt = datetime('now');
            dt.Format = 'ddMMyyyy_HHmm';
            sim_out=app.sim_out;
            folder = uigetdir(pwd, 'Select Folder To Save Data');
            if folder == 0
                disp('User canceled. No file saved.');
            else
                timestamp = string(dt);
                filename = "SavedRun_" + timestamp + ".mat";                   
                fullpath = fullfile(folder, char(filename));           
                save(fullpath, 'sim_out');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Visualization of Sim Results')
            xlabel(app.UIAxes, 'x-axis')
            ylabel(app.UIAxes, 'y-axis')
            zlabel(app.UIAxes, 'z-axis')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SimulateButton = uibutton(app.GridLayout2, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Icon = fullfile(pathToMLAPP, 'GUI_elements', 'run_simulink.svg');
            app.SimulateButton.Text = 'Simulate';
            app.LoadModelButton = uibutton(app.GridLayout2, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.EditField = uieditfield(app.GridLayout2, 'text');
            app.Exercise3 = uipanel(app.GridLayout2);
            app.Exercise3.Enable = 'off';
            app.Exercise3.Title = 'Exercise 3';
            app.Exercise3.Visible = 'off';
            app.GridLayout6 = uigridlayout(app.Exercise3);
            app.YawstepdegEditFieldLabel = uilabel(app.GridLayout6);
            app.YawstepdegEditFieldLabel.Text = 'Yaw step [deg]:';
            app.YawstepdegEditField = uieditfield(app.GridLayout6, 'numeric');
            app.YawstepdegEditField.ValueChangedFcn = createCallbackFcn(app, @YawstepdegEditFieldValueChanged, true);
            app.P_zEditFieldLabel = uilabel(app.GridLayout6);
            app.P_zEditFieldLabel.Text = 'P_z';
            app.P_zEditField = uieditfield(app.GridLayout6, 'numeric');
            app.P_zEditField.ValueChangedFcn = createCallbackFcn(app, @P_zEditFieldValueChanged, true);
            app.I_zEditFieldLabel = uilabel(app.GridLayout6);
            app.I_zEditFieldLabel.Text = 'I_z';
            app.I_zEditField = uieditfield(app.GridLayout6, 'numeric');
            app.I_zEditField.ValueChangedFcn = createCallbackFcn(app, @I_zEditFieldValueChanged, true);
            app.D_zEditFieldLabel = uilabel(app.GridLayout6);
            app.D_zEditFieldLabel.Text = 'D_z';
            app.D_zEditField = uieditfield(app.GridLayout6, 'numeric');
            app.D_zEditField.ValueChangedFcn = createCallbackFcn(app, @D_zEditFieldValueChanged, true);
            app.P_yawEditFieldLabel = uilabel(app.GridLayout6);
            app.P_yawEditFieldLabel.Text = 'P_yaw';
            app.P_yawEditField = uieditfield(app.GridLayout6, 'numeric');
            app.P_yawEditField.ValueChangedFcn = createCallbackFcn(app, @P_yawEditFieldValueChanged, true);
            app.I_yawEditFieldLabel = uilabel(app.GridLayout6);
            app.I_yawEditFieldLabel.Text = 'I_yaw';
            app.I_yawEditField = uieditfield(app.GridLayout6, 'numeric');
            app.I_yawEditField.ValueChangedFcn = createCallbackFcn(app, @I_yawEditFieldValueChanged, true);
            app.D_yawEditFieldLabel = uilabel(app.GridLayout6);
            app.D_yawEditFieldLabel.Text = 'D_yaw';
            app.D_yawEditField = uieditfield(app.GridLayout6, 'numeric');
            app.D_yawEditField.ValueChangedFcn = createCallbackFcn(app, @D_yawEditFieldValueChanged, true);
            app.YawPIDGainsLabel = uilabel(app.GridLayout6);
            app.YawPIDGainsLabel.Text = 'Yaw PID Gains';
            app.AltitudePIDGainsLabel = uilabel(app.GridLayout6);
            app.AltitudePIDGainsLabel.Text = 'Altitude PID Gains';
            app.Exercise2 = uipanel(app.GridLayout2);
            app.Exercise2.Title = 'Exercise 2';
            app.GridLayout5 = uigridlayout(app.Exercise2);
            app.CtrlMassDeviationSliderLabel = uilabel(app.GridLayout5);
            app.CtrlMassDeviationSliderLabel.Text = 'Ctrl Mass Deviation [%]';
            app.CtrlMassDeviationSlider = uislider(app.GridLayout5);
            app.CtrlMassDeviationSlider.Limits = [-5 5];
            app.CtrlMassDeviationSlider.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.CtrlMassDeviationSlider.ValueChangedFcn = createCallbackFcn(app, @CtrlMassDeviationSliderValueChanged, true);
            app.CtrlMassDeviationSlider.MinorTicks = [-5 -4.5 -4 -3.5 -3 -2.5 -2 -1.5 -1 -0.5 0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.CtrlMassDeviationSlider.Step = 1;
            app.LayoutOffsetLabel = uilabel(app.GridLayout5);
            app.LayoutOffsetLabel.Text = 'Layout Offset in X [%]';
            app.LayoutOffsetinXSlider = uislider(app.GridLayout5);
            app.LayoutOffsetinXSlider.Limits = [-5 5];
            app.LayoutOffsetinXSlider.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.LayoutOffsetinXSlider.ValueChangedFcn = createCallbackFcn(app, @LayoutOffsetinXSliderValueChanged, true);
            app.LayoutOffsetinXSlider.MinorTicks = [-5 -4.5 -4 -3.5 -3 -2.5 -2 -1.5 -1 -0.5 0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.LayoutOffsetinXSlider.Step = 1;
            app.ControllerGainsDropDownLabel = uilabel(app.GridLayout5);
            app.ControllerGainsDropDownLabel.Text = 'Controller Gains';
            app.ControllerGainsDropDown = uidropdown(app.GridLayout5);
            app.ControllerGainsDropDown.Items = {'Continuos', '20 Hz', '50 Hz', '100 Hz', '200 Hz'};
            app.ControllerGainsDropDown.ItemsData = {'cont', '20 Hz', '50 Hz', '100 Hz', '"200 Hz"'};
            app.ControllerGainsDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerGainsDropDownValueChanged, true);
            app.ControllerGainsDropDown.Value = 'cont';
            app.LayoutOffsetSliderLabel_2 = uilabel(app.GridLayout5);
            app.LayoutOffsetSliderLabel_2.Text = 'Sample Rates';
            app.ControllerRateDropDownLabel = uilabel(app.GridLayout5);
            app.ControllerRateDropDownLabel.Text = 'Controller Rate';
            app.ControllerRateDropDown = uidropdown(app.GridLayout5);
            app.ControllerRateDropDown.Items = {'Continuos', '20 Hz', '50 Hz', '100 Hz', '200 Hz'};
            app.ControllerRateDropDown.ItemsData = {'cont', '20 Hz', '50 Hz', '100 Hz', '"200 Hz"'};
            app.ControllerRateDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerRateDropDownValueChanged, true);
            app.ControllerRateDropDown.Value = 'cont';
            app.SensorsDropDownLabel = uilabel(app.GridLayout5);
            app.SensorsDropDownLabel.Text = 'Sensors';
            app.SensorsDropDown = uidropdown(app.GridLayout5);
            app.SensorsDropDown.Items = {'Continuos', '20 Hz', '50 Hz', '100 Hz', '200 Hz'};
            app.SensorsDropDown.ItemsData = {'cont', '20 Hz', '50 Hz', '100 Hz', '200 Hz'};
            app.SensorsDropDown.ValueChangedFcn = createCallbackFcn(app, @SensorsDropDownValueChanged, true);
            app.SensorsDropDown.Value = 'cont';
            app.SaveRunButton = uibutton(app.GridLayout2, 'push');
            app.SaveRunButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRunButtonPushed, true);
            app.SaveRunButton.Icon = fullfile(pathToMLAPP, 'GUI_elements', 'saveAs.svg');
            app.SaveRunButton.Text = 'Save Run';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Button_2 = uibutton(app.GridLayout3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'GUI_elements', 'run.svg');
            app.Button_2.Text = '';
            app.Slider = uislider(app.GridLayout3);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [0 10];
            app.Button = uibutton(app.GridLayout3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'GUI_elements', 'pause.svg');
            app.Button.Text = '';
            app.QuadRotorPSLabLabel = uilabel(app.GridLayout);
            app.QuadRotorPSLabLabel.Text = 'Quad-Rotor P&S Lab ';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.UIAxes_xpos = uiaxes(app.GridLayout4);
            title(app.UIAxes_xpos, 'x - position')
            ylabel(app.UIAxes_xpos, '[m]')
            zlabel(app.UIAxes_xpos, 'Z')
            app.UIAxes_ypos = uiaxes(app.GridLayout4);
            title(app.UIAxes_ypos, 'y - position')
            ylabel(app.UIAxes_ypos, '[m]')
            zlabel(app.UIAxes_ypos, 'Z')
            app.UIAxes_zpos = uiaxes(app.GridLayout4);
            title(app.UIAxes_zpos, 'z - position')
            ylabel(app.UIAxes_zpos, '[m]')
            zlabel(app.UIAxes_zpos, 'Z')
            app.UIAxes_pitch = uiaxes(app.GridLayout4);
            title(app.UIAxes_pitch, 'Roll')
            ylabel(app.UIAxes_pitch, '[rad]')
            zlabel(app.UIAxes_pitch, 'Z')
            app.UIAxes_roll = uiaxes(app.GridLayout4);
            title(app.UIAxes_roll, 'Pitch')
            ylabel(app.UIAxes_roll, '[rad]')
            zlabel(app.UIAxes_roll, 'Z')
            app.UIAxes_yaw = uiaxes(app.GridLayout4);
            title(app.UIAxes_yaw, 'Yaw')
            ylabel(app.UIAxes_yaw, '[rad]')
            zlabel(app.UIAxes_yaw, 'Z')
            app.UIAxes_motors = uiaxes(app.GridLayout4);
            title(app.UIAxes_motors, 'Thrusts')
            ylabel(app.UIAxes_motors, '[N]')
            zlabel(app.UIAxes_motors, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PS_Quadcopter_Lab_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end